classdef dataRecordApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        dataRecordUIFigure         matlab.ui.Figure
        ch1Panel                   matlab.ui.container.Panel
        ch1Axes                    matlab.ui.control.UIAxes
        ch2Panel                   matlab.ui.container.Panel
        ch2Axes                    matlab.ui.control.UIAxes
        ch3Panel                   matlab.ui.container.Panel
        ch3Axes                    matlab.ui.control.UIAxes
        ch4Panel                   matlab.ui.container.Panel
        ch4Axes                    matlab.ui.control.UIAxes
        DevicePanel                matlab.ui.container.Panel
        SelectdeviceDropDownLabel  matlab.ui.control.Label
        devicesDropDown            matlab.ui.control.DropDown
        ConnectButton              matlab.ui.control.Button
        connectionLabel            matlab.ui.control.Label
        SendtoliveButton           matlab.ui.control.Button
        liveLabel                  matlab.ui.control.Label
        RecordPanel                matlab.ui.container.Panel
        MovementDropDownLabel      matlab.ui.control.Label
        MovementDropDown           matlab.ui.control.DropDown
        SubjectEditFieldLabel      matlab.ui.control.Label
        SubjectEditField           matlab.ui.control.EditField
        recordButton               matlab.ui.control.Button
        recordEndButton            matlab.ui.control.Button
        triallabelEditFieldLabel   matlab.ui.control.Label
        trialEditField             matlab.ui.control.EditField
    end


    properties (Access = public)
        bitalinoObj; % Description
        ch1Data;
        ch2Data;
        ch3Data;
        ch4Data; % Description
        availableDevices;
        connection;
        isStreaming;
        isRecording;
        timerObj;
        ch1Plot;
        ch2Plot;
        ch3Plot;
        ch4Plot; % Description
    end

    methods (Access = private)
    
        function timerFcn_callback(app)
            % reads data from buffer
            rawData = read(app.bitalinoObj);
            nData = size(rawData,1);
            if nData == 0
                disp('no data read. wait, restart or check connection')
            end
            % convert adc data to emg data (mV)
            ch1Data_new = adc2emg(rawData(:,6));
            ch2Data_new = adc2emg(rawData(:,7));
            ch3Data_new = adc2emg(rawData(:,8));
            ch4Data_new = adc2emg(rawData(:,9));
            % add new data to old data if data recording is on
            if(app.isRecording == 1)
                app.ch1Data = [app.ch1Data; ch1Data_new];
                app.ch2Data = [app.ch2Data; ch2Data_new];
                app.ch3Data = [app.ch3Data; ch3Data_new];
                app.ch4Data = [app.ch4Data; ch4Data_new];
            end
            
            % plot data
            ch1Data_toplot = [get(app.ch1Plot,'YData') ch1Data_new'];
            ch2Data_toplot = [get(app.ch2Plot,'YData') ch2Data_new'];
            ch3Data_toplot = [get(app.ch3Plot,'YData') ch3Data_new'];
            ch4Data_toplot = [get(app.ch4Plot,'YData') ch4Data_new'];
                        % if data array is bigger than 5000 points, cuts array to the most
            % recent 5000 data points
            if length(ch1Data_toplot) > 5000
                ch1Data_toplot = ch1Data_toplot(end-5000+1:end);
                ch2Data_toplot = ch2Data_toplot(end-5000+1:end);
                ch3Data_toplot = ch3Data_toplot(end-5000+1:end);
                ch4Data_toplot = ch4Data_toplot(end-5000+1:end);
            end
            set(app.ch1Plot,'XData',1:5000,'YData',ch1Data_toplot);
            set(app.ch2Plot,'XData',1:5000,'YData',ch2Data_toplot);
            set(app.ch3Plot,'XData',1:5000,'YData',ch3Data_toplot);
            set(app.ch4Plot,'XData',1:5000,'YData',ch4Data_toplot);
            refreshdata;
            drawnow;
            
           
        end
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.availableDevices=Bitalino.findDevices();
            app.devicesDropDown.Items = app.availableDevices';
            app.isStreaming = 0;
            app.isRecording = 0;
            app.bitalinoObj = [];
            app.connection = 0;
            app.ch1Plot = plot(app.ch1Axes,0);
            set(app.ch1Axes,'XTickLabel',[]);
            axis tight; grid on;
            app.ch2Plot = plot(app.ch2Axes,0);
            set(app.ch2Axes,'XTickLabel',[]);
            axis tight; grid on;
            app.ch3Plot = plot(app.ch3Axes,0);
            set(app.ch3Axes,'XTickLabel',[]);
            axis tight; grid on;
            app.ch4Plot = plot(app.ch4Axes,0);
            set(app.ch4Axes,'XTickLabel',[]);
            axis tight; grid on;
            app.timerObj = timer('TimerFcn',@(~,~) timerFcn_callback(app),'Period',0.1, ...
                 'ExecutionMode','FixedRate','BusyMode','drop');
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if(app.connection == 0)
                try
                    app.bitalinoObj = Bitalino(app.devicesDropDown.Value);
                    app.connection = 1;
                    app.ConnectButton.Text = 'Disconnect';
                    app.connectionLabel.Text = 'Connected';
                    disp('Connection established');
                catch
                    error('Connection failed');
                end
            elseif(app.connection == 1)
                delete(app.bitalinoObj);
                app.connection = 0;
                app.ConnectionButton.Text = 'Connect';
                app.connectionLabel.Text = 'Disconnected';
            end
        end

        % Button pushed function: SendtoliveButton
        function SendtoliveButtonPushed(app, event)
            if(app.connection == 1)
               if(app.bitalinoObj.isStreaming == false)
                  startBackground(app.bitalinoObj);
                  app.isStreaming = app.bitalinoObj.isStreaming;
                  if(strcmp(app.timerObj.Running,'off'))
                      start(app.timerObj);
                  end
                  app.liveLabel.Text = 'on';
               elseif(app.bitalinoObj.isStreaming == true)
                   if(strcmp(app.timerObj.Running,'on'))
                       stop(app.timerObj);
                   end
                   stopBackground(app.bitalinoObj);
                   app.isStreaming = app.bitalinoObj.isStreaming;
                   app.liveLabel.Text = 'off';
               end
            end
        end

        % Button pushed function: recordButton
        function recordButtonPushed(app, event)
            if(app.isRecording == 0)
                app.isRecording = 1;
                % before staring, empty the data record variables
                app.ch1Data = [];
                app.ch2Data = [];
                app.ch3Data = [];
                app.ch4Data = [];
            end
        end

        % Button pushed function: recordEndButton
        function recordEndButtonPushed(app, event)
             % save data to 
            filepathtowrite = ['data/' app.SubjectEditField.Value '/record/' app.MovementDropDown.Value '_' app.trialEditField.Value '.csv'];
            writetable(table(app.ch1Data,app.ch2Data,app.ch3Data,app.ch4Data),filepathtowrite);
            % empty the data after saving
            app.ch1Data = [];
            app.ch2Data = [];
            app.ch3Data = [];
            app.ch4Data = [];
            app.isRecording = 0;
        end

        % Close request function: dataRecordUIFigure
        function dataRecordUIFigureCloseRequest(app, event)
            if(strcmp(app.timerObj.Running,'off'))
                stop(app.timerObj);
            end
            if(app.bitalinoObj.isStreaming == 1)
                stopBackground(app.bitalinoObj);
            end
            delete(app.timerObj);
            delete(app.bitalinoObj);
            
            delete(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.dataRecordUIFigure = uifigure;
            app.dataRecordUIFigure.Name = 'Data record';
            app.dataRecordUIFigure.CloseRequestFcn = createCallbackFcn(app, @dataRecordUIFigureCloseRequest, true);
            setAutoResize(app, app.dataRecordUIFigure, true)
            app.ch1Panel = uipanel(app.dataRecordUIFigure);
            app.ch1Panel.Title = 'Channel1';
            app.ch1Axes = uiaxes(app.ch1Panel);
            app.ch2Panel = uipanel(app.dataRecordUIFigure);
            app.ch2Panel.Title = 'Channel2';
            app.ch2Axes = uiaxes(app.ch2Panel);
            app.ch3Panel = uipanel(app.dataRecordUIFigure);
            app.ch3Panel.Title = 'Channel3';
            app.ch3Axes = uiaxes(app.ch3Panel);
            app.ch4Panel = uipanel(app.dataRecordUIFigure);
            app.ch4Panel.Title = 'Channel4';
            app.ch4Axes = uiaxes(app.ch4Panel);
            app.DevicePanel = uipanel(app.dataRecordUIFigure);
            app.DevicePanel.Title = 'Device';
            app.SelectdeviceDropDownLabel = uilabel(app.DevicePanel);
            app.SelectdeviceDropDownLabel.Text = 'Select device';
            app.devicesDropDown = uidropdown(app.DevicePanel);
            app.ConnectButton = uibutton(app.DevicePanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.connectionLabel = uilabel(app.DevicePanel);
            app.connectionLabel.Text = 'disconnected';
            app.SendtoliveButton = uibutton(app.DevicePanel, 'push');
            app.SendtoliveButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoliveButtonPushed, true);
            app.SendtoliveButton.Text = 'Send to live';
            app.liveLabel = uilabel(app.DevicePanel);
            app.liveLabel.Text = 'off';
            app.RecordPanel = uipanel(app.dataRecordUIFigure);
            app.RecordPanel.Title = 'Record';
            app.MovementDropDownLabel = uilabel(app.RecordPanel);
            app.MovementDropDownLabel.Text = 'Movement';
            app.MovementDropDown = uidropdown(app.RecordPanel);
            app.MovementDropDown.Items = {'fist', 'point', 'wrist_flexion', 'wrist_extension', 'wrist_pronation', 'wrist_supination'};
            app.MovementDropDown.Value = 'fist';
            app.SubjectEditFieldLabel = uilabel(app.RecordPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.RecordPanel, 'text');
            app.recordButton = uibutton(app.RecordPanel, 'push');
            app.recordButton.ButtonPushedFcn = createCallbackFcn(app, @recordButtonPushed, true);
            app.recordButton.Text = 'Start';
            app.recordEndButton = uibutton(app.RecordPanel, 'push');
            app.recordEndButton.ButtonPushedFcn = createCallbackFcn(app, @recordEndButtonPushed, true);
            app.recordEndButton.Text = 'End';
            app.triallabelEditFieldLabel = uilabel(app.RecordPanel);
            app.triallabelEditFieldLabel.Text = 'trial label';
            app.trialEditField = uieditfield(app.RecordPanel, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = dataRecordApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.dataRecordUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dataRecordUIFigure)
        end
    end
end